classdef control_panel < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        ControlPanelLabel     matlab.ui.control.Label
        InputEditFieldLabel   matlab.ui.control.Label
        InputEditField        matlab.ui.control.EditField
        StandbyModeLampLabel  matlab.ui.control.Label
        StandbyModeLamp       matlab.ui.control.Lamp
        FlightModeLampLabel   matlab.ui.control.Label
        FlightModeLamp        matlab.ui.control.Lamp
        LaunchModeLampLabel   matlab.ui.control.Label
        LaunchModeLamp        matlab.ui.control.Lamp
        OutputTable           matlab.ui.control.Table
    end

    
    properties (Access = private)
        serial_port;
        port_flag=false;
    end


    methods (Access = public)
        
        function update_output(app,string,port)
               test_output(app,string);                   %check to see if any mode was activated
               
               %Get serial port
               if (app.port_flag == false)
                    app.serial_port=port;
                    app.port_flag=true;
               end
               %Update Table
               oldData = get(app.OutputTable,'Data');
               newRow={string};
               newData = [newRow; oldData];
               set(app.OutputTable,'Data',newData);
        end
        
    end
    
    methods (Access = private)
        
        function test_output(app,s)
            try
                s(end)=[];
            catch
            end
            
            switch true
                case (strcmp('STANDBY MODE',s)==1)
                    app.StandbyModeLamp.Enable= true;
                    app.LaunchModeLamp.Enable= false;
                    app.FlightModeLamp.Enable= false;
                case (strcmp('LAUNCH MODE',s)==1)
                    app.StandbyModeLamp.Enable= false;
                    app.LaunchModeLamp.Enable= true;
                    app.FlightModeLamp.Enable= false;
                case (strcmp('FLIGHT MODE',s)==1)
                    app.StandbyModeLamp.Enable= false;
                    app.LaunchModeLamp.Enable= false;
                    app.FlightModeLamp.Enable= true;
            end

        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: InputEditField
        function InputEditFieldValueChanged(app, event)
            value = app.InputEditField.Value;
            if (strcmp(value,"RED"))
               disp("está ok");
               writeline(app.serial_port,"RED");
            end
            app.InputEditField.Value= ''
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ControlPanelLabel = uilabel(app.UIFigure);
            app.ControlPanelLabel.Text = 'Control Panel';
            app.InputEditFieldLabel = uilabel(app.UIFigure);
            app.InputEditFieldLabel.Text = 'Input';
            app.InputEditField = uieditfield(app.UIFigure, 'text');
            app.InputEditField.ValueChangedFcn = createCallbackFcn(app, @InputEditFieldValueChanged, true);
            app.StandbyModeLampLabel = uilabel(app.UIFigure);
            app.StandbyModeLampLabel.Text = 'Stand-by Mode';
            app.StandbyModeLamp = uilamp(app.UIFigure);
            app.FlightModeLampLabel = uilabel(app.UIFigure);
            app.FlightModeLampLabel.Text = 'Flight Mode';
            app.FlightModeLamp = uilamp(app.UIFigure);
            app.FlightModeLamp.Enable = 'off';
            app.LaunchModeLampLabel = uilabel(app.UIFigure);
            app.LaunchModeLampLabel.Text = 'Launch Mode';
            app.LaunchModeLamp = uilamp(app.UIFigure);
            app.LaunchModeLamp.Enable = 'off';
            app.OutputTable = uitable(app.UIFigure);
            app.OutputTable.ColumnName = {'Output'};
            app.OutputTable.RowName = {};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = control_panel

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end